classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        AmplitudeSliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        DropDown              matlab.ui.control.DropDown
    end


    properties (Access = private)
        xdata % Description
        ydata % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xdata = linspace(0,3*pi,10);
            app.ydata = cos(app.xdata) + rand(1,10);
            plot(app.UIAxes,app.xdata,app.ydata);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000,1000];
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
                        value = app.DropDown.Value;
            if strcmp(value,'Line Plot')
                plot(app.UIAxes,app.xdata,app.ydata);
            elseif strcmp(value,'Scatter Plot')
                scatter(app.UIAxes, app.xdata, app.ydata);
            else
                plot(app.UIAxes,app.xdata,app.ydata);
                hold(app.UIAxes,'on');
                scatter(app.UIAxes, app.xdata,app.ydata);
                hold(app.UIAxes,'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end