classdef startupApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartacquisitionButton         matlab.ui.control.Button
        TakedarkButton                 matlab.ui.control.Button
        StopacquisitionButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        IntegrationTimemsEditField     matlab.ui.control.NumericEditField
        UniqueStudyNameEditFieldLabel  matlab.ui.control.Label
        UniqueStudyNameEditField       matlab.ui.control.EditField
        WaittimemsEditFieldLabel       matlab.ui.control.Label
        WaittimemsEditField            matlab.ui.control.NumericEditField
        WasatchWP785ContinuousSamplingLabel  matlab.ui.control.Label
        DayleKotturi2018Label          matlab.ui.control.Label
        WaveNumberforratiodenominator0noratioEditFieldLabel  matlab.ui.control.Label
        WaveNumberforratiodenominator0noratioEditField  matlab.ui.control.NumericEditField
        LaserPowerasfractionoffullpowerEditFieldLabel  matlab.ui.control.Label
        LaserPowerasfractionoffullpowerEditField  matlab.ui.control.NumericEditField
    end

    % Dayle Kotturi August 2018
    properties (Access = private)
        
        integrationTimeMS
        waitTimeMS
        darkStem
        rawStem
        dataStem
        darkData
        rawData
        spectrumData
        lastSpectrumData
        difference
        darkFilename
        rawFilename
        spectrumFilename
        spectrometer
        wavenumbers
        numPoints
        severeError
        systemOn
        systemCollecting
        x
        xMin
        xMax
        yMin
        yMax
        firstTime
        refWaveNumber
        laserPower
        studyName
        previousStudyName
        closestRef
    end

    methods (Access = private)
        
        function a = takeSpectrum(app)    
            % get a spectrum
            app.spectrometer.integrationTimeMS = app.integrationTimeMS;
            spectrum = app.spectrometer.getSpectrum(); 
            pixels = app.spectrometer.pixels;
            if (pixels > app.numPoints)
                fprintf('ERROR: number of pixels, %d, exceeds expected, %d\n', pixels, ...
                app.numPoints);
                app.severeError = true;
            else 
                fprintf('INFO: takeSpectrum retrieved %d pixels\n', pixels);
            end
            a = spectrum;
        end
              
        % Need to pass in stem in addition to app because this function
        % called for different types of files
        function b = writeSpectrumToFile(app, myData, stem)
            size(app.x) % This is reported as 1x1024
            size(myData) % This is reported as 1x1
            
            % If this is ratiometric comparison, store the spectrum/reference peak
            if (app.refWaveNumber ~= 0)
                myData = myData/myData(app.refWaveNumber);
            end
    
            % create a data file from the current date and time
            filename = sprintf(stem, datestr(now,'yyyy-mm-dd-HH-MM-SS'));
            [fileID,errmsg] = fopen(filename,'w');
            fprintf('%s opened for write\n', filename);
            
            % write the number of pixels first (to help the read routine)
               
            % write the spectrum to file before it is overwritten
            for i = 1:app.spectrometer.pixels
                fprintf(fileID, "%g %g \n", app.x(i), myData(i)); % use notepad++ to see this is 1 value per line for 1024 lines
                %fprintf(fileID, "%g %g\n", wavenumbers(i), spectrum(i)); % use notepad++ to see that this is all 1024 values on one line!
            end   
            
            % append additional fields (so it can change over time 
            % without changing where data is in the file)
            % laser power as fraction of full power
            
            % cleanup
            fclose(fileID);
            b = filename; 
        end
        
        function c = plotDark(app)
            % local function to graph the spectrum
            figure
            %plot(wavelengths, spectrum, 'blue', wavelengths, difference, 'red');
            %plot(wavenumbers, spectrum, 'blue', wavenumbers, difference, 'red');
            plot(app.UIAxes, app.wavenumbers, dark, 'black');
            title(app.UIAxes, app.darkFilename); % put filename of spectrum on the plot for traceability
            xlabel(app.UIAxes, 'Wavenumber (cm^-1)'); % x-axis label
            ylabel(app.UIAxes, 'Arbitrary Units (A.U.)'); % y-axis label
            %legend('blue = intensity','red = difference','Location','northoutside');
            %legend('green = raw intensity','Location','northoutside');
            %xlim([xMin xMax]);
            %ylim([yMin yMax]);  
            c = 1;
        end
        
        % The control loop driving the acquisition. 
        %
        % It is entered by the action of the start button being pressed. It is exited 
        % by the action of the stop button being pressed. 
        %
        % If the stop button press ceases to be detected, increase
        % the wait time between acquisitions. If the wait time is zero, the stop button
        % is never seen; control stays in the loop and the callback for the stop button 
        % isn't seen.
        function d = acquisitionControl(app)
            while (app.systemCollecting == true)
                checkForSevereError(app); % check for errors prior to each acquisition

                fprintf("\n2. Taking spectrum...");
                app.rawData = takeSpectrum(app);
                
                app.rawFilename = writeSpectrumToFile(app, app.rawData, app.rawStem);
                pause(app.waitTimeMS); % the pb that stop button does not get
                % noticed may be because the wait time is zero
                fprintf("3. Subtracting dark (can be all zeros if no dark given)...");
                % instead of taking a spectrum, we are calculating it from previous
                for i = 1:app.spectrometer.pixels
                    app.spectrumData(i) = app.rawData(i) - app.darkData(i);
                end
                app.spectrumFilename = writeSpectrumToFile(app, app.spectrumData, ...
                    app.dataStem);
                
                if (app.firstTime == false)
                    for i = 1:app.spectrometer.pixels
                        app.difference(i) = app.spectrumData(i) - app.lastSpectrumData(i);
                    end
                else
                    app.difference = zeros();
                end
                
                % plot this iteration
                plotStatus = plotSpectrum(app);
                
                % prepare for next iteration
                for i = 1:app.spectrometer.pixels
                    app.lastSpectrumData(i) = app.spectrumData(i);
                end
                
                % clear flag for all iterations > 1
                if (app.firstTime == true)
                    app.firstTime = false;
                end
            end
            d = 1;
        end
        
        function e = checkForSevereError(app)           	
            if (app.severeError == true)
                f = warndlg('Unrecoverable error detected. Exiting.','Error');
                delete(app);
            end
            e = 1;
        end
        
        function f = plotSpectrum(app)
            % local function to graph the spectrum
            title(app.UIAxes3, app.spectrumFilename); % put filename of spectrum on the plot for traceability
            %figure % Idea: https://www.mathworks.com/matlabcentral/answers/392617-how-can-i-use-ginput-in-app-designer
            %subplot(2,2,1)
            %plot(wavelengths, spectrum, 'blue', wavelengths, difference, 'red');
            %plot(wavenumbers, spectrum, 'blue', wavenumbers, difference, 'red');
            plot(app.UIAxes3, app.wavenumbers, app.rawData, 'green');
            title(app.UIAxes3, 'Raw');
            xlabel(app.UIAxes3, 'Wavenumber (cm^-1)'); % x-axis label
            ylabel(app.UIAxes3, 'Arbitrary Units (A.U.)'); % y-axis label
            %legend('blue = intensity','red = difference','Location','northoutside');
            %legend('green = raw intensity','Location','northoutside');
            %xlim([xMin xMax]);
            %ylim([yMin yMax]);
            
            %subplot(2,2,2)
            %plot(wavelengths, dark, 'black');
            plot(app.UIAxes, app.wavenumbers, app.darkData, 'black');
            title(app.UIAxes, 'Dark');
            xlabel(app.UIAxes, 'Wavenumber (cm^-1)'); % x-axis label
            ylabel(app.UIAxes,'Arbitrary Units (A.U.)'); % y-axis label
            %legend('black = intensity of dark','Location','northoutside');
            %xlim([xMin xMax]);
            %ylim([yMin yMax]);
            
            %subplot(2,2,3)
            %plot(wavelengths, spectrum, 'blue');
            plot(app.UIAxes4, app.wavenumbers, app.spectrumData, 'blue');
            title(app.UIAxes4, 'Raw - Dark');
            xlabel(app.UIAxes4, 'Wavenumber (cm^-1)'); % x-axis label
            ylabel(app.UIAxes4, 'Arbitrary Units (A.U.)'); % y-axis label
            %xlim([xMin xMax]);
            %ylim([yMin yMax]);
            
            % Replace difference with ratiometric data
            %if (app.firstTime == false)
            % if not firstTime, do the plot the difference
            %    %subplot(2,2,4)
            %    %plot(wavelengths, difference, 'red');
            %    plot(app.UIAxes5, app.wavenumbers, app.difference, 'red');
            %    title(app.UIAxes5, 'Difference to previous');
            %    xlabel(app.UIAxes5, 'Wavenumber (cm^-1)'); % x-axis label
            %    ylabel(app.UIAxes5, 'Arbitrary Units (A.U.)'); % y-axis label
            %    %xlim([xMin xMax]);
            %    %ylim([yMin yMax]);
            %end
            
            if (app.closestRef ~= 0)
                %subplot(2,2,4)
                %plot(wavelengths, difference, 'red');
                plot(app.UIAxes5, app.wavenumbers, app.spectrumData/app.spectrumData(app.closestRef), 'red');
                myTitle = sprintf('Ratiometric: as fraction of %g at %4.0f', ...
                    spectrum(app.closestRef), app.wavenumbers(app.closestRef));
                title(app.UIAxes5, myTitle);
                xlabel(app.UIAxes5, 'Wavenumber (cm^-1)'); % x-axis label
                ylabel(app.UIAxes5, '(A.U.)/(A.U.)'); % y-axis label
                %xlim([xMin xMax]);
                %ylim([yMin yMax]);
            end
            f = 1;
        end
        
        % TO DO: finish. dir returns a list of files, not the answer to uniqueness
        % http://matlab.izmiran.ru/help/techdoc/ref/dir.html
        function g = isStudyNameUnique(app)
            g = 0;
            % look at all directories in data directory to see if this name exists
            d = '../../Data';
            f = dir(fullfile(d, app.studyName));
            g = f;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Optionally take a dark at the start of every run. This is the spectra
            % that is seen when the laser is off. Store it. Subtract it from 
            % each subsequent spectra. What is hard about this is that the sample is in
            % the flowcell, screwed in with 12 screws and a liquid seal. Ideally, the
            % "dark" would be the flowcell, sealed and screwed together without the
            % sample. When the sample is just sitting on quartz, it is easier. Assuming
            % quartz homogeneous, slide can just be repositioned to move sample out of
            % laser line.
            
            % Add possibility of using a dark from file instead of taking it at the
            % start of the run. This feature is needed when a dark cannot be taken
            % because the sample is sealed inside the flowcell. Once per day or per
            % run, the flowcell full of fluid could be sensed to create a new dark.
            app.severeError = false;
            app.numPoints = 1024; % TO DO: change Wasatch API to allow changes
            app.systemCollecting = false;
            app.integrationTimeMS = 5000; % default, initially write this in box
            app.waitTimeMS = 0; % default. initially this is written in box
            app.xMin = 0;
            app.xMax = 1800;
            app.yMin = 0;
            app.yMax = 12000;
            % Relative to where Matlab files are. If using the ones in git checkout,
            % \\bme-fs-student2.engr.tamu.edu\graduate\dayle.kotturi\Documents\GitHub\spectrometerTools
            % use:
            app.darkStem = '../../Data/dark-%s.txt'; % TO DO: Add in study name dir
            app.rawStem = '../../Data/rawSpectrum-%s.txt'; % TO DO:Add in study name dir
            app.dataStem = '../../Data/spectrum-%s.txt'; % TO DO:Add in study name dir
            laserWavelengthNM = 785.0; % This is constant for this spectrometer
            app.darkData = zeros(1, app.numPoints, 'double');
            app.spectrumData = zeros(1, app.numPoints, 'double');
            app.lastSpectrumData = zeros(1, app.numPoints, 'double');
            % load the DLL
            %32 bit dll = NET.addAssembly('C:\Program Files (x86)\Wasatch Photonics\Wasatch.NET\WasatchNET.dll');
            dll = NET.addAssembly('C:\Program Files\Wasatch Photonics\Wasatch.NET\WasatchNET.dll');
            
            % get a handle to the Driver Singleton
            driver = WasatchNET.Driver.getInstance();
            
            % enumerate any connected spectrometers
            numberOfSpectrometers = driver.openAllSpectrometers();
            fprintf('INFO: %d spectrometers found.\n', numberOfSpectrometers);
            if numberOfSpectrometers <= 0
            	app.severeError = true;
                f = warndlg('No spectrometer connected. Is it powered on?','Warning');
                delete(app);
            else
                % open the first spectrometer found
                app.spectrometer = driver.getSpectrometer(0);
                
                % access some key properties
                pixels = app.spectrometer.pixels;
                modelName = char(app.spectrometer.model);
                serialNum = char(app.spectrometer.serialNumber);
                wavelengths = app.spectrometer.wavelengths;
                app.wavenumbers = WasatchNET.Util.wavelengthsToWavenumbers(laserWavelengthNM,... 
                    wavelengths);
                
                % this should not be necessary but it helps to make wavenumber into 1x1024
                % array instead of 1x1.
                for i = 1:pixels
                    app.x(i) = app.wavenumbers(i);
                end
                
                % display summary
                fprintf('INFO: found %s %s with %d pixels (%.2f, %.2fnm)\n', ...
                    modelName, serialNum, pixels, ...
                    wavelengths(1), wavelengths(wavelengths.Length));
                for i = 1:pixels
                    fprintf("%g %g \n", app.wavenumbers(i), app.spectrumData(i)); % use notepad++ to see this is 1 value per line for 1024 lines
                end 
                %size(wavenumbers); % This is misleading b/c it is 1x1
                app.firstTime = true;
            end
        end

        % Value changed function: IntegrationTimemsEditField
        function IntegrationTimemsEditFieldValueChanged(app, event)
            % This entry box is only editable when system is stopped, i.e not acquiring
            % See Start/StopacquisitionButtonPushed
            app.integrationTimeMS = app.IntegrationTimemsEditField.Value;
        end

        % Value changed function: 
        % LaserPowerasfractionoffullpowerEditField
        function LaserPowerasfractionoffullpowerEditFieldValueChanged(app, event)
            app.laserPower = app.LaserPowerasfractionoffullpowerEditField.Value;
        end

        % Button pushed function: StartacquisitionButton
        function StartacquisitionButtonPushed(app, event)
            app.systemCollecting = true;
            app.IntegrationTimemsEditField.Editable = 'off';
            app.WaittimemsEditField.Editable = 'off';
            acquisitionControl(app); % starts the acquistion loop
        end

        % Button pushed function: StopacquisitionButton
        function StopacquisitionButtonPushed(app, event)
            app.systemCollecting = false;
            app.IntegrationTimemsEditField.Editable = 'on';
            app.WaittimemsEditField.Editable = 'on';
        end

        % Button pushed function: TakedarkButton
        function TakedarkButtonPushed(app, event)
            app.darkData = takeSpectrum(app);
            
            % check. for debugging. 1024 values are there. Just not vis in workspace ?!
            %for i=1:app.numPoints
            %    fprintf('darkData(%d)=%d\n', i, app.darkData(i));
            %end
            
            app.darkFilename = writeSpectrumToFile(app, app.darkData, app.darkStem);
        end

        % Value changed function: UniqueStudyNameEditField
        function UniqueStudyNameEditFieldValueChanged(app, event)
            app.previousStudyName = app.studyName;
            app.studyName = app.UniqueStudyNameEditField.Value;
            if (isStudyNameUnique(app)) 
                fprintf('%s is unique\n', app.studyName);
            else
                app.studyName = app.previousStudyName;
            end
        end

        % Value changed function: WaittimemsEditField
        function WaittimemsEditFieldValueChanged(app, event)
            app.waitTimeMS = app.WaittimemsEditField.Value;
            
        end

        % Value changed function: 
        % WaveNumberforratiodenominator0noratioEditField
        function WaveNumberforratiodenominator0noratioEditFieldValueChanged(app, event)
            app.closestRef = app.WaveNumberforratiodenominator0noratioEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartacquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartacquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartacquisitionButtonPushed, true);
            app.StartacquisitionButton.Text = 'Start acquisition';
            app.TakedarkButton = uibutton(app.UIFigure, 'push');
            app.TakedarkButton.ButtonPushedFcn = createCallbackFcn(app, @TakedarkButtonPushed, true);
            app.TakedarkButton.Text = 'Take dark';
            app.StopacquisitionButton = uibutton(app.UIFigure, 'push');
            app.StopacquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopacquisitionButtonPushed, true);
            app.StopacquisitionButton.Text = 'Stop acquisition';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.IntegrationTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.IntegrationTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegrationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimemsEditFieldValueChanged, true);
            app.IntegrationTimemsEditField.Value = 5000;
            app.UniqueStudyNameEditFieldLabel = uilabel(app.UIFigure);
            app.UniqueStudyNameEditFieldLabel.Text = 'Unique Study Name';
            app.UniqueStudyNameEditField = uieditfield(app.UIFigure, 'text');
            app.UniqueStudyNameEditField.ValueChangedFcn = createCallbackFcn(app, @UniqueStudyNameEditFieldValueChanged, true);
            app.UniqueStudyNameEditField.Value = 'WP785';
            app.WaittimemsEditFieldLabel = uilabel(app.UIFigure);
            app.WaittimemsEditFieldLabel.Text = 'Wait time (ms)';
            app.WaittimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaittimemsEditField.ValueChangedFcn = createCallbackFcn(app, @WaittimemsEditFieldValueChanged, true);
            app.WaittimemsEditField.Value = 10;
            app.WasatchWP785ContinuousSamplingLabel = uilabel(app.UIFigure);
            app.WasatchWP785ContinuousSamplingLabel.Text = 'Wasatch WP785 Continuous Sampling';
            app.DayleKotturi2018Label = uilabel(app.UIFigure);
            app.DayleKotturi2018Label.Text = 'Dayle Kotturi 2018';
            app.WaveNumberforratiodenominator0noratioEditFieldLabel = uilabel(app.UIFigure);
            app.WaveNumberforratiodenominator0noratioEditFieldLabel.Text = 'Wave Number for ratio denominator (0 = no ratio)';
            app.WaveNumberforratiodenominator0noratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaveNumberforratiodenominator0noratioEditField.ValueChangedFcn = createCallbackFcn(app, @WaveNumberforratiodenominator0noratioEditFieldValueChanged, true);
            app.LaserPowerasfractionoffullpowerEditFieldLabel = uilabel(app.UIFigure);
            app.LaserPowerasfractionoffullpowerEditFieldLabel.Text = 'Laser Power as fraction of full power';
            app.LaserPowerasfractionoffullpowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaserPowerasfractionoffullpowerEditField.ValueChangedFcn = createCallbackFcn(app, @LaserPowerasfractionoffullpowerEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = startupApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end